classdef PsyCam < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        PsyCamUIFigure                matlab.ui.Figure
        conditionsinglegroupButton    matlab.ui.control.Button
        conditionspairedgroupsButton  matlab.ui.control.Button
        Panel                         matlab.ui.container.Panel
        PsyCamModeSelectionLabel      matlab.ui.control.Label
        Image                         matlab.ui.control.Image
        SimilaritySSIMButton          matlab.ui.control.Button
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            addpath("PsyCam_AUX_func");
            movegui(app.PsyCamUIFigure,'center');
            pause(0.25);
        end

        % Button pushed function: conditionsinglegroupButton
        function conditionsinglegroupButtonPushed(app, event)
        pause(0.5);
        close(app.PsyCamUIFigure);
        PsyCam_singlesubject;
        end

        % Button pushed function: conditionspairedgroupsButton
        function conditionspairedgroupsButtonPushed(app, event)
            pause(0.5);
            close(app.PsyCamUIFigure);
            PsyCam_dualsubject;
        end

        % Button pushed function: SimilaritySSIMButton
        function SimilaritySSIMButtonPushed(app, event)
            pause(0.5);
            close(app.PsyCamUIFigure);
            PsyCam_ssi;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.PsyCamUIFigure = uifigure('Visible', 'off');
            app.PsyCamUIFigure.Name = 'PsyCam';
            app.conditionsinglegroupButton = uibutton(app.PsyCamUIFigure, 'push');
            app.conditionsinglegroupButton.ButtonPushedFcn = createCallbackFcn(app, @conditionsinglegroupButtonPushed, true);
            app.conditionsinglegroupButton.Text = {'1 condition '; '(single group)'};
            app.conditionspairedgroupsButton = uibutton(app.PsyCamUIFigure, 'push');
            app.conditionspairedgroupsButton.ButtonPushedFcn = createCallbackFcn(app, @conditionspairedgroupsButtonPushed, true);
            app.conditionspairedgroupsButton.Text = {'2 conditions'; '(paired groups)'};
            app.Panel = uipanel(app.PsyCamUIFigure);
            app.PsyCamModeSelectionLabel = uilabel(app.Panel);
            app.PsyCamModeSelectionLabel.Text = 'PsyCam - Mode Selection';
            app.Image = uiimage(app.Panel);
            app.Image.ImageSource = 'evanlab.png';
            app.SimilaritySSIMButton = uibutton(app.PsyCamUIFigure, 'push');
            app.SimilaritySSIMButton.ButtonPushedFcn = createCallbackFcn(app, @SimilaritySSIMButtonPushed, true);
            app.SimilaritySSIMButton.Text = {'Similarity '; '(SSIM)'};
            % Show the figure after all components are created
            app.PsyCamUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = PsyCam

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.PsyCamUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.PsyCamUIFigure)
        end
    end
end