classdef PsyCam_dualsubject < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        PsyCamDualConditionUIFigure  matlab.ui.Figure
        GridLayout                   matlab.ui.container.GridLayout
        LeftPanel                    matlab.ui.container.Panel
        Panel                        matlab.ui.container.Panel
        PsyCamEvanLabLabel           matlab.ui.control.Label
        Image                        matlab.ui.control.Image
        dualsubjectmodeLabel         matlab.ui.control.Label
        LoadconditionsPanel          matlab.ui.container.Panel
        Cond2Button                  matlab.ui.control.Button
        Cond1Button                  matlab.ui.control.Button
        PairButton                   matlab.ui.control.Button
        Lamp2                        matlab.ui.control.Lamp
        Lamp1                        matlab.ui.control.Lamp
        BinningSizeButtonGroup       matlab.ui.container.ButtonGroup
        Binning1                     matlab.ui.control.RadioButton
        Binning4                     matlab.ui.control.RadioButton
        Binning16                    matlab.ui.control.RadioButton
        Binning64                    matlab.ui.control.RadioButton
        Binning128                   matlab.ui.control.RadioButton
        n1024Button                  matlab.ui.control.RadioButton
        n4096Button                  matlab.ui.control.RadioButton
        ProcessButton                matlab.ui.control.Button
        ClearButton                  matlab.ui.control.Button
        SaveLog                      matlab.ui.control.Button
        RightPanel                   matlab.ui.container.Panel
        SaveImg                      matlab.ui.control.Button
        PlotPanel                    matlab.ui.container.Panel
        PlotAxes                     matlab.ui.control.UIAxes
        FileDropDownLabel            matlab.ui.control.Label
        FileDropDown                 matlab.ui.control.DropDown
        SaveallButton                matlab.ui.control.Button
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    
    properties (Access = private)
        data;
        data1;
        data2;
        data_diff;
        ack=[1 0];
        bin_size;
        metrics;
        DialogApp;% Description% Description
    end
    
    methods (Access = public)
        
        function update_reorder(app,data_struct_out)
            app.data1.neworder=data_struct_out.string1;
            app.data2.neworder=data_struct_out.string2;
        end
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            movegui(app.PsyCamDualConditionUIFigure,'center');
            pause(0.25);
            axtoolbar(app.PlotAxes,'visible','off');
            app.data.path_temp='';
        end

        % Button pushed function: Cond1Button
        function Cond1ButtonPushed(app, event)
            [file, path]=uigetfile({'*.DNG','*.dng'},'Load DNG files','MultiSelect','on');
            
            %if press cancel or close the window
            if isequal(file,0)
                return;
            else
                
                app.Lamp1.Color='r';
                
                if ~iscell(file)
                    f=uiconfirm(app.PsyCamDualConditionUIFigure,'Warning! You selected only one file!','Warning!','options',{'Ok'},'Icon','warning');
                    waitfor(f);
                end
                
                file=cellstr(file);
                n=numel(file);
                
                cfa_cell=cell(1,n);
                filename_cell=cell(1,n);
                
                %create a waitbar
                wb=uiprogressdlg(app.PsyCamDualConditionUIFigure,"Title",'Loading DNG files...');
                warning off;
                
                for i=1:n
                    path_temp=strcat(path,file{i});
                    cfa_temp=load_dng(path_temp);
                    cfa_cell{i}=cfa_temp;
                    filename_cell{i}=strcat(cfa_temp.path,'\', cfa_temp.name,'.DNG');
                    wb.Value=i/n;
                    wb.Message=sprintf('File %.0f over %.0f (%s)',i,n,cfa_temp.name);
                end
                
                delete(wb);
                
                %store the cfa
                app.data1.img=cfa_cell;
                app.data1.filelist=filename_cell;
                app.data1.neworder=filename_cell;
                app.ack(1)=1;
                app.Lamp1.Color='g';
                
                if sum(app.ack)==2
                    
                    %enable binning btns
                    set(findall(app.BinningSizeButtonGroup, '-property', 'enable'), 'enable', 'on')
                    
                    %enable computing e pair btn
                    app.ProcessButton.Enable='on';
                    app.PairButton.Enable='on';
                    
                end
            end
            
            %enable binning btns
            %set(findall(app.BinningSizeButtonGroup, '-property', 'enable'), 'enable', 'on')
            
            %enable computing btn
            %app.ProcessButton.Enable='on';
        end

        % Button pushed function: Cond2Button
        function Cond2ButtonPushed(app, event)
            [file, path]=uigetfile({'*.DNG','*.dng'},'Load DNG files','MultiSelect','on');
            
            %if press cancel or close the window
            if isequal(file,0)
                return;
            else
                
                app.Lamp2.Color='r';
                
                if ~iscell(file)
                    f=uiconfirm(app.PsyCamDualConditionUIFigure,'Warning! You selected only one file!','Warning!','options',{'Ok'},'Icon','warning');
                    waitfor(f);
                end
                
                file=cellstr(file);
                n=numel(file);
                
                cfa_cell=cell(1,n);
                filename_cell=cell(1,n);
                
                %create a waitbar
                wb=uiprogressdlg(app.PsyCamDualConditionUIFigure,"Title",'Loading DNG files...');
                
                warning off;
                
                for i=1:n
                    path_temp=strcat(path,file{i});
                    cfa_temp=load_dng(path_temp);
                    cfa_cell{i}=cfa_temp;
                    filename_cell{i}=strcat(cfa_temp.path,'\', cfa_temp.name,'.DNG');
                    wb.Value=i/n;
                    wb.Message=sprintf('File %.0f over %.0f (%s)',i,n,cfa_temp.name);
                end
                
                delete(wb);
                
                %store the cfa
                app.data2.img=cfa_cell;
                app.data2.filelist=filename_cell;
                app.data2.neworder=filename_cell;
                app.ack(2)=1;
                app.Lamp2.Color='g';
                
                if sum(app.ack)==2
                    
                    %enable binning btns
                    set(findall(app.BinningSizeButtonGroup, '-property', 'enable'), 'enable', 'on')
                    
                    %enable computing e pair btn
                    app.ProcessButton.Enable='on';
                    app.PairButton.Enable='on';
                    
                end
            end
            
        end

        % Button pushed function: PairButton
        function PairButtonPushed(app, event)
            app.DialogApp=PsyCam_reorder(app,string(app.data1.filelist),string(app.data2.filelist));
        end

        % Button pushed function: ProcessButton
        function ProcessButtonPushed(app, event)
            if numel(app.data1.filelist)==numel(app.data2.filelist)
                
                n=numel(app.data1.filelist);
                wb=uiprogressdlg(app.PsyCamDualConditionUIFigure,"Title",'Processing DNG files...');
                
                if ~isempty(app.data1.neworder)
                    %reorder data1
                    old_order=string(app.data1.filelist);
                    new_order=string(app.data1.neworder);
                    
                    [~,pos]=ismember(new_order,old_order);
                    
                    app.data1.filelist=app.data1.filelist(pos);
                    app.data1.img=app.data1.img(pos);
                    app.data1.neworder=[];
                end
                
                if ~isempty(app.data2.neworder)
                    %reorder data2
                    old_order=string(app.data2.filelist);
                    new_order=string(app.data2.neworder);
                    
                    [~,pos]=ismember(new_order,old_order);
                    
                    app.data2.filelist=app.data2.filelist(pos);
                    app.data2.img=app.data2.img(pos);
                    app.data2.neworder=[];
                end
                
                if isempty(app.bin_size)
                    app.bin_size=1;
                end
                
                bin_size=app.bin_size;
                cfa_cell1=app.data1.img;
                cfa_cell2=app.data2.img;
                
                %initialize table
                typevar={'string','double','double','double','double'};
                namevar={'Filename','BinSize','MAE','MDE','MSDE'};
                metrics_table=table('Size',[n,5],'VariableTypes',typevar,'VariableNames',namevar);
                
                min_all=zeros(1,n);
                max_all=zeros(1,n);
                
                for i=1:n
                    
                    %======================condition1
                    struct_temp=cfa_cell1{i};
                    cfa_temp=struct_temp.cfa;
                    name_temp1=struct_temp.name;
                    
                    img_temp1=binning_matrix(cfa_temp,bin_size);
                    
                    %store in the struct
                    app.data1.img{i}.bin_size=bin_size;
                    app.data1.img{i}.bin_matrix=img_temp1;
                    
                    
                    %======================condition2
                    struct_temp=cfa_cell2{i};
                    cfa_temp=struct_temp.cfa;
                    name_temp2=struct_temp.name;
                    
                    img_temp2=binning_matrix(cfa_temp,bin_size);
                    
                    %store in the struct
                    app.data2.img{i}.bin_size=bin_size;
                    app.data2.img{i}.bin_matrix=img_temp2;
                    
                    
                    %=======================Metrics
                    metrics_temp=metrics_matrix_dualsubject(img_temp1,img_temp2);
                    metrics_table(i,:)=[cellstr(strcat(cellstr(name_temp1),'_VS_',cellstr(name_temp2))),num2cell([bin_size,metrics_temp])];
                    
                    %store the difference
                    app.data_diff.data{i}=img_temp1-img_temp2;
                    app.data_diff.filelist{i}=strcat(name_temp1,'_VS_',name_temp2);
                    
                    range1=double(app.data1.img{i}.range);
                    range2=double(app.data2.img{i}.range);
                    app.data_diff.range{i}=[-max([range1,range2]),max([range1,range2])];
                    
                    min_all(i)=min(min(img_temp1-img_temp2));
                    max_all(i)=max(max(img_temp1-img_temp2));
                    
                    
                    %update waitbar
                    wb.Value=i/n;
                    wb.Message=sprintf('File %.0f over %.0f (%s VS %s)',i,n,name_temp1, name_temp2);
                end
                
                %default min-max scaling values (for images)
                min_all=min(min_all);
                max_all=max(max_all);
                
                %auto or manual scaling?
                f=uiconfirm(app.PsyCamDualConditionUIFigure,'Select the scaling options','Scaling options','options',{'Auto','Manual'},'Icon','question');
                waitfor(f);
                
                if isequal(f,'Manual')
                    ff=inputdlg({'Min','Max'},'Set manual scaling values',[1 50],string([min_all,max_all]));
                    waitfor(ff);
                    min_all=str2double(ff{1});
                    max_all=str2double(ff{2});
                end
                
                 %if the limits are flat, let's create a "fake" superior limit
                if isequal(min_all,max_all)
                    max_all=min_all+eps;
                end
                
                %store range
                app.data.range=[min_all, max_all];
                app.metrics=metrics_table;
                
                delete(wb);
                
            else
                uialert(app.PsyCamDualConditionUIFigure,'Cond. 1 and Cond. 2 files are in different quantity!','Quantity error')
                
                %remove fields from app.data{1,2}
                app.data1=rmfield(app.data1,fieldnames(app.data1));
                app.data2=rmfield(app.data2,fieldnames(app.data2));
                
                %turn lights red
                app.Lamp1.Color='r';
                app.Lamp2.Color='r';
                
                %disable process button, binning size and pair button
                app.ProcessButton.Enable='off';
                set(findall(app.BinningSizeButtonGroup, '-property', 'enable'), 'enable', 'off')
                app.PairButton.Enable='off';
                
            end


            f=uiconfirm(app.PsyCamDualConditionUIFigure,'Do you want to save the results?','Save?','options',{'Yes','No'},'Icon','warning');
            waitfor(f);
            
            if isequal(f,'Yes')
                
                %save table to csv
                [file,path] = uiputfile('*.csv','Save results');
                
                if ~isequal(file,0)
                    wb=uiprogressdlg(app.PsyCamDualConditionUIFigure,"Title",'Saving results...','Indeterminate','on');
                    writetable(metrics_table,strcat(path,file));
                    delete(wb);
                end
                
            end
            
            app.SaveLog.Enable='on';
            app.FileDropDown.Items=cellstr(string(app.data_diff.filelist));
            app.FileDropDown.Enable='on';
            
            %plot image
            
            %clear axes
            cla(app.PlotAxes);
            value = app.FileDropDown.Value;
            index = find([app.data_diff.filelist{:}] == value);
            img_temp=app.data_diff.data{index};
            range_temp=app.data_diff.range{index}; %range for difference
            
            app.data.img_temp=img_temp;
            app.data.range_temp=range_temp;
            app.data.filename_temp=value;
            
            
            %scale axes
            r=size(img_temp,2);
            
            if r>1
                x=[1, size(img_temp,2)];
                y=[size(img_temp,1), 1];
                imagesc(app.PlotAxes,x,y,img_temp);
                app.PlotAxes.YDir='normal';
                colormap(app.PlotAxes,"hsv");
                app.PlotAxes.XLim=[1 r];
                app.PlotAxes.YLim=[1 r];
                app.PlotAxes.CLim=[app.data.range(1),app.data.range(2)];
                cb=colorbar(app.PlotAxes,'eastoutside','Fontsize',10);
                cb.Label.String=sprintf('Range = [%.0f; %.0f]',range_temp(1),range_temp(2));
                cb.Label.FontSize=12;
                tix=get(cb,'xtick')';
                set(cb,'xticklabel',num2str(tix,'%.2f'));
                %cb.Lable
                %title(app.PlotAxes,sprintf('Min = %.3f, Max=%.3f',range_temp(1),range_temp(2)),'FontSize',10,'FontWeight',"normal")
                app.data.colorbar=cb;
            else
                app.PlotAxes.XLim=[-1 1];
                app.PlotAxes.YLim=[-1 1];
                text(app.PlotAxes,0,0,sprintf('Val = %f\nRange = [ %.0f; %.0f]',img_temp,range_temp(1),range_temp(2)),'HorizontalAlignment','center','FontSize',18);
                colorbar(app.PlotAxes,'off');
            end
            
            drawnow;
            
            app.SaveImg.Enable='on';
            app.FileDropDown.Enable='on';
            app.SaveallButton.Enable='on';
            
        end

        % Button pushed function: SaveLog
        function SaveLogButtonPushed(app, event)
            [file,path] = uiputfile('*.csv','Save results');
            
            if ~isequal(file,0)
                metrics_table=app.metrics_table;
                wb=uiprogressdlg(app.PsyCamUIFigure,"Title",'Saving results...','Indeterminate','on');
                writetable(metrics_table,strcat(path,file));
                delete(wb);
            end
            
        end

        % Button pushed function: ClearButton
        function ClearButtonPushed(app, event)
            delete(app.PsyCamDualConditionUIFigure);
            pause(0.5);
            PsyCam_dualsubject;
        end

        % Close request function: PsyCamDualConditionUIFigure
        function PsyCamDualConditionUIFigureCloseRequest(app, event)
            delete(app)
            PsyCam;
        end

        % Selection changed function: BinningSizeButtonGroup
        function BinningSizeButtonGroupSelectionChanged(app, event)
            %clear image and colorbar
            cla(app.PlotAxes);
            colorbar(app.PlotAxes,'off');
            
            %disable saving buttons & list
            app.SaveLog.Enable='off';
            app.SaveImg.Enable='off';
            app.FileDropDown.Enable='off';
            app.SaveallButton.Enable='off';
            
            selectedButton = app.BinningSizeButtonGroup.SelectedObject;
            
            %extract the bin size from the selected group
            txt=selectedButton.Text;
            txt_cell=strsplit(txt,':');
            bin_size=str2double(txt_cell{2});
            
            %store bin size
            app.bin_size=bin_size;
        end

        % Value changed function: FileDropDown
        function FileDropDownValueChanged(app, event)
            
            cla(app.PlotAxes);
            app.SaveImg.Enable='off';
            app.SaveallButton.Enable='off';
            
            value = app.FileDropDown.Value;
            index = find([app.data_diff.filelist{:}] == value);
            img_temp=app.data_diff.data{index};
            range_temp=app.data_diff.range{index};
            
            app.data.img_temp=img_temp;
            app.data.filename_temp=value;
            
            %scale axes
            r=size(img_temp,2);
            
            if r>1
                x=[1, size(img_temp,2)];
                y=[size(img_temp,1), 1];
                imagesc(app.PlotAxes,x,y,img_temp);
                app.PlotAxes.YDir='normal';
                colormap(app.PlotAxes,"hsv");
                app.PlotAxes.XLim=[1 r];
                app.PlotAxes.YLim=[1 r];
                app.PlotAxes.CLim=[app.data.range(1),app.data.range(2)];
                cb=colorbar(app.PlotAxes,'eastoutside','Fontsize',10);
                cb.Label.String=sprintf('Range = [%.0f; %.0f]',range_temp(1),range_temp(2));
                cb.Label.FontSize=12;
                tix=get(cb,'xtick')';
                set(cb,'xticklabel',num2str(tix,'%.2f'));
                %title(app.PlotAxes,sprintf('Min = %.3f, Max=%.3f',range_temp(1),range_temp(2)),'FontSize',18)
                app.data.colorbar=cb;
            else
                app.PlotAxes.XLim=[-1 1];
                app.PlotAxes.YLim=[-1 1];
                text(app.PlotAxes,0,0,sprintf('Val = %f\nRange = [%.0f; %.0f]',img_temp,range_temp(1),range_temp(2)),'HorizontalAlignment','center','FontSize',18);
                colorbar(app.PlotAxes,'off');
                
            end
            
            drawnow;
            
            app.SaveImg.Enable='on';
            app.SaveallButton.Enable='on';
            
        end

        % Button pushed function: SaveImg
        function SaveImgButtonPushed(app, event)
            %load temporary path
            path_temp=app.data.path_temp;
            
            img_temp=app.data.img_temp;
            def_file=app.data.filename_temp;
            [~, def_name]=fileparts(def_file);
            
            folder=uigetdir(path_temp,'Select saving folder');
            
            if ~isequal(folder,0)
                %update temporary folder
                app.data.path_temp=folder;
                
                wb=uiprogressdlg(app.PsyCamDualConditionUIFigure,"Title",'Saving image...','Indeterminate','on');
                
                img_struct.(def_name)=img_temp;
                save(strcat(folder,'\',def_name),'-struct','img_struct');
                
                save_UIaxes(app.PlotAxes,app.data.colorbar,strcat(folder,'\',def_name));
                delete(wb);
            end
            
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.PsyCamDualConditionUIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {491, 491};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {372, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end

        % Button pushed function: SaveallButton
        function SaveallButtonPushed(app, event)
            range=app.data.range;
            range_temp=app.data.range_temp;
            n=numel(app.data_diff.data);
            names=app.data_diff.filelist;
            
            folder=uigetdir('','Select saving folder');
            
            if ~isequal(folder,0)
                
                wb=uiprogressdlg(app.PsyCamDualConditionUIFigure,"Title",'Saving files...');
                
                for i=1:n
                    img_temp=app.data_diff.data{i};
                    [~, name_temp]=fileparts(names{i});
                    r=size(img_temp,2);
                    
                    if r>1
                        
                        %create a new figure and plot the image
                        fig = figure;
                        fig.Visible = 'off';
                        figAxes = axes(fig);
                        
                        x=[1, size(img_temp,2)];
                        y=[size(img_temp,1), 1];
                        imagesc(figAxes,x,y,img_temp);
                        figAxes.YDir='normal';
                        colormap(app.PlotAxes,"hsv");
                        figAxes.XLim=[1 r];
                        figAxes.YLim=[1 r];
                        figAxes.CLim=range;
                        figAxes.XTick=[];
                        figAxes.YTick=[];
                        cb=colorbar(figAxes,'eastoutside','Fontsize',10);
                        cb.Label.String=sprintf('Range = [%.0f; %.0f]',range_temp(1),range_temp(2));
                        cb.Label.FontSize=12;
                        tix=get(cb,'xtick')';
                        set(cb,'xticklabel',num2str(tix,'%.2f'));
                        
                        %save the image
                        saveas(fig,strcat(folder,'\',name_temp),'png');
                        
                        close(fig);
                        
                        %update waitbar
                        wb.Value=i/n;
                        
                    end
                    
                    img_struct.(name_temp)=img_temp;
                    save(strcat(folder,'\',name_temp),'-struct','img_struct');
                    wb.Message=sprintf('Saving file %.0f over %.0f (%s)',i,n,name_temp);
                    
                end
                
                delete(wb);
                
            end
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.PsyCamDualConditionUIFigure = uifigure('Visible', 'off');
            app.PsyCamDualConditionUIFigure.Name = 'PsyCam - dual subject';
            app.PsyCamDualConditionUIFigure.CloseRequestFcn = createCallbackFcn(app, @PsyCamDualConditionUIFigureCloseRequest, true);
            app.PsyCamDualConditionUIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.PsyCamDualConditionUIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.Panel = uipanel(app.LeftPanel);
            app.PsyCamEvanLabLabel = uilabel(app.Panel);
            app.PsyCamEvanLabLabel.Text = 'PsyCam - EvanLab';
            app.Image = uiimage(app.Panel);
            app.Image.ImageSource = 'evanlab.png';
            app.dualsubjectmodeLabel = uilabel(app.Panel);
            app.dualsubjectmodeLabel.Text = '[dual subject mode]';
            app.LoadconditionsPanel = uipanel(app.LeftPanel);
            app.LoadconditionsPanel.Title = 'Load conditions';
            app.Cond2Button = uibutton(app.LoadconditionsPanel, 'push');
            app.Cond2Button.ButtonPushedFcn = createCallbackFcn(app, @Cond2ButtonPushed, true);
            app.Cond2Button.Text = 'Cond. 2';
            app.Cond1Button = uibutton(app.LoadconditionsPanel, 'push');
            app.Cond1Button.ButtonPushedFcn = createCallbackFcn(app, @Cond1ButtonPushed, true);
            app.Cond1Button.Text = 'Cond. 1';
            app.PairButton = uibutton(app.LoadconditionsPanel, 'push');
            app.PairButton.ButtonPushedFcn = createCallbackFcn(app, @PairButtonPushed, true);
            app.PairButton.Enable = 'off';
            app.PairButton.Text = 'Pair';
            app.Lamp2 = uilamp(app.LoadconditionsPanel);
            app.Lamp1 = uilamp(app.LoadconditionsPanel);
            app.BinningSizeButtonGroup = uibuttongroup(app.LeftPanel);
            app.BinningSizeButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @BinningSizeButtonGroupSelectionChanged, true);
            app.BinningSizeButtonGroup.Title = 'Binning Size';
            app.Binning1 = uiradiobutton(app.BinningSizeButtonGroup);
            app.Binning1.Enable = 'off';
            app.Binning1.Tooltip = {''};
            app.Binning1.Text = 'n:1';
            app.Binning1.Value = true;
            app.Binning4 = uiradiobutton(app.BinningSizeButtonGroup);
            app.Binning4.Enable = 'off';
            app.Binning4.Tooltip = {'4'};
            app.Binning4.Text = 'n:4';
            app.Binning16 = uiradiobutton(app.BinningSizeButtonGroup);
            app.Binning16.Enable = 'off';
            app.Binning16.Tooltip = {'16'};
            app.Binning16.Text = 'n:16';
            app.Binning64 = uiradiobutton(app.BinningSizeButtonGroup);
            app.Binning64.Enable = 'off';
            app.Binning64.Tooltip = {'64'};
            app.Binning64.Text = 'n:64';
            app.Binning128 = uiradiobutton(app.BinningSizeButtonGroup);
            app.Binning128.Enable = 'off';
            app.Binning128.Tooltip = {'128'; ''};
            app.Binning128.Text = 'n:256';
            app.n1024Button = uiradiobutton(app.BinningSizeButtonGroup);
            app.n1024Button.Enable = 'off';
            app.n1024Button.Text = 'n:1024';
            app.n4096Button = uiradiobutton(app.BinningSizeButtonGroup);
            app.n4096Button.Enable = 'off';
            app.n4096Button.Text = 'n:4096';
            app.ProcessButton = uibutton(app.LeftPanel, 'push');
            app.ProcessButton.ButtonPushedFcn = createCallbackFcn(app, @ProcessButtonPushed, true);
            app.ProcessButton.Enable = 'off';
            app.ProcessButton.Text = 'Process';
            app.ClearButton = uibutton(app.LeftPanel, 'push');
            app.ClearButton.ButtonPushedFcn = createCallbackFcn(app, @ClearButtonPushed, true);
            app.ClearButton.Text = 'Clear';
            app.SaveLog = uibutton(app.LeftPanel, 'push');
            app.SaveLog.ButtonPushedFcn = createCallbackFcn(app, @SaveLogButtonPushed, true);
            app.SaveLog.Enable = 'off';
            app.SaveLog.Text = 'Save';
            app.RightPanel = uipanel(app.GridLayout);
            app.SaveImg = uibutton(app.RightPanel, 'push');
            app.SaveImg.ButtonPushedFcn = createCallbackFcn(app, @SaveImgButtonPushed, true);
            app.SaveImg.Enable = 'off';
            app.SaveImg.Text = 'Save';
            app.PlotPanel = uipanel(app.RightPanel);
            app.PlotAxes = uiaxes(app.PlotPanel);
            title(app.PlotAxes, '')
            xlabel(app.PlotAxes, '')
            ylabel(app.PlotAxes, '')
            app.PlotAxes.XTick = [];
            app.PlotAxes.YTick = [];
            app.FileDropDownLabel = uilabel(app.RightPanel);
            app.FileDropDownLabel.Enable = 'off';
            app.FileDropDownLabel.Text = {'File'; ''};
            app.FileDropDown = uidropdown(app.RightPanel);
            app.FileDropDown.Items = {};
            app.FileDropDown.ValueChangedFcn = createCallbackFcn(app, @FileDropDownValueChanged, true);
            app.FileDropDown.Enable = 'off';
            app.FileDropDown.Value = {};
            app.SaveallButton = uibutton(app.RightPanel, 'push');
            app.SaveallButton.ButtonPushedFcn = createCallbackFcn(app, @SaveallButtonPushed, true);
            app.SaveallButton.Enable = 'off';
            app.SaveallButton.Text = 'Save all';
            % Show the figure after all components are created
            app.PsyCamDualConditionUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = PsyCam_dualsubject

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.PsyCamDualConditionUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.PsyCamDualConditionUIFigure)
        end
    end
end