classdef PsyCam_reorder < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        PsyCamreorderUIFigure  matlab.ui.Figure
        OkButton               matlab.ui.control.Button
        Condition1Panel        matlab.ui.container.Panel
        UPButton_1             matlab.ui.control.Button
        DOWNButton_1           matlab.ui.control.Button
        GroupListBox_1         matlab.ui.control.ListBox
        Condition2Panel        matlab.ui.container.Panel
        UPButton_2             matlab.ui.control.Button
        DOWNButton_2           matlab.ui.control.Button
        GroupListBox_2         matlab.ui.control.ListBox
        Panel                  matlab.ui.container.Panel
        PsyCamEvanLabLabel     matlab.ui.control.Label
        Image                  matlab.ui.control.Image
        reordertoolLabel       matlab.ui.control.Label
    end

    
    properties (Access = private)
        string_temp1;
        string_temp2;
        value1;
        value2;
        CallingApp;
    end
    
    properties (Access = public)
        data_out % Description
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainapp, string1, string2)
            app.CallingApp=mainapp;
            
            app.string_temp1=string1;
            app.string_temp2=string2;
            app.GroupListBox_1.Items=string1;
            app.GroupListBox_2.Items=string2;
            
            movegui(app.PsyCamreorderUIFigure,"center");
        end

        % Value changed function: GroupListBox_1
        function GroupListBox_1ValueChanged(app, event)
            app.value1 = string(app.GroupListBox_1.Value);
            app.UPButton_1.Enable="on";
            app.DOWNButton_1.Enable="on";
            
        end

        % Button pushed function: UPButton_1
        function UPButton_1Pushed(app, event)
            pos=find(app.string_temp1==app.value1);
            
            if pos>1
                
                string_temp_temp=app.string_temp1;
                string_temp_temp(pos-1)=app.string_temp1(pos);
                string_temp_temp(pos)=app.string_temp1(pos-1);
                
                string_temp=string_temp_temp;
                
                app.string_temp1=string_temp;
                
                app.GroupListBox_1.Items=app.string_temp1;
            end
            
            
        end

        % Button pushed function: DOWNButton_1
        function DOWNButton_1Pushed(app, event)
            
            pos=find(app.string_temp1==app.value1);
            
            if pos<numel(app.string_temp1)
                
                string_temp_temp=app.string_temp1;
                string_temp_temp(pos+1)=app.string_temp1(pos);
                string_temp_temp(pos)=app.string_temp1(pos+1);
                
                string_temp=string_temp_temp;
                
                app.string_temp1=string_temp;
                
                app.GroupListBox_1.Items=app.string_temp1;
            end
            
        end

        % Button pushed function: UPButton_2
        function UPButton_2Pushed(app, event)
            pos=find(app.string_temp2==app.value2);
            
            if pos>1
                
                string_temp_temp=app.string_temp2;
                string_temp_temp(pos-1)=app.string_temp2(pos);
                string_temp_temp(pos)=app.string_temp2(pos-1);
                
                string_temp=string_temp_temp;
                
                app.string_temp2=string_temp;
                
                app.GroupListBox_2.Items=app.string_temp2;
            end
            
            
        end

        % Button pushed function: DOWNButton_2
        function DOWNButton_2Pushed(app, event)
            pos=find(app.string_temp2==app.value2);
            
            if pos<numel(app.string_temp2)
                
                string_temp_temp=app.string_temp2;
                string_temp_temp(pos+1)=app.string_temp2(pos);
                string_temp_temp(pos)=app.string_temp2(pos+1);
                
                string_temp=string_temp_temp;
                
                app.string_temp2=string_temp;
                
                app.GroupListBox_2.Items=app.string_temp2;
            end
        end

        % Value changed function: GroupListBox_2
        function GroupListBox_2ValueChanged(app, event)
            app.value2 = string(app.GroupListBox_2.Value);
            app.UPButton_2.Enable="on";
            app.DOWNButton_2.Enable="on";
            
        end

        % Button pushed function: OkButton
        function OkButtonPushed(app, event)
            data_out.string1=app.string_temp1;
            data_out.string2=app.string_temp2;
            
            update_reorder(app.CallingApp,data_out);
            
            close(app.PsyCamreorderUIFigure);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.PsyCamreorderUIFigure = uifigure('Visible', 'off');
            app.PsyCamreorderUIFigure.Name = 'PsyCam - reorder';
            app.OkButton = uibutton(app.PsyCamreorderUIFigure, 'push');
            app.OkButton.ButtonPushedFcn = createCallbackFcn(app, @OkButtonPushed, true);
            app.OkButton.Text = 'Ok';
            app.Condition1Panel = uipanel(app.PsyCamreorderUIFigure);
            app.Condition1Panel.Title = 'Condition 1';
            app.UPButton_1 = uibutton(app.Condition1Panel, 'push');
            app.UPButton_1.ButtonPushedFcn = createCallbackFcn(app, @UPButton_1Pushed, true);
            app.UPButton_1.Enable = 'off';
            app.UPButton_1.Text = 'UP';
            app.DOWNButton_1 = uibutton(app.Condition1Panel, 'push');
            app.DOWNButton_1.ButtonPushedFcn = createCallbackFcn(app, @DOWNButton_1Pushed, true);
            app.DOWNButton_1.Enable = 'off';
            app.DOWNButton_1.Text = 'DOWN';
            app.GroupListBox_1 = uilistbox(app.Condition1Panel);
            app.GroupListBox_1.Items = {};
            app.GroupListBox_1.ValueChangedFcn = createCallbackFcn(app, @GroupListBox_1ValueChanged, true);
            app.GroupListBox_1.Value = {};
            app.Condition2Panel = uipanel(app.PsyCamreorderUIFigure);
            app.Condition2Panel.Title = 'Condition 2';
            app.UPButton_2 = uibutton(app.Condition2Panel, 'push');
            app.UPButton_2.ButtonPushedFcn = createCallbackFcn(app, @UPButton_2Pushed, true);
            app.UPButton_2.Enable = 'off';
            app.UPButton_2.Text = 'UP';
            app.DOWNButton_2 = uibutton(app.Condition2Panel, 'push');
            app.DOWNButton_2.ButtonPushedFcn = createCallbackFcn(app, @DOWNButton_2Pushed, true);
            app.DOWNButton_2.Enable = 'off';
            app.DOWNButton_2.Text = 'DOWN';
            app.GroupListBox_2 = uilistbox(app.Condition2Panel);
            app.GroupListBox_2.Items = {};
            app.GroupListBox_2.ValueChangedFcn = createCallbackFcn(app, @GroupListBox_2ValueChanged, true);
            app.GroupListBox_2.Value = {};
            app.Panel = uipanel(app.PsyCamreorderUIFigure);
            app.PsyCamEvanLabLabel = uilabel(app.Panel);
            app.PsyCamEvanLabLabel.Text = 'PsyCam - EvanLab';
            app.Image = uiimage(app.Panel);
            app.Image.ImageSource = 'evanlab.png';
            app.reordertoolLabel = uilabel(app.Panel);
            app.reordertoolLabel.Text = ' [reorder tool]';
            % Show the figure after all components are created
            app.PsyCamreorderUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = PsyCam_reorder(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.PsyCamreorderUIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.PsyCamreorderUIFigure)
        end
    end
end