classdef PsyCam_singlesubject < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        PsyCamsinglesubjectUIFigure  matlab.ui.Figure
        GridLayout                   matlab.ui.container.GridLayout
        LeftPanel                    matlab.ui.container.Panel
        LoadButton                   matlab.ui.control.Button
        ProcessButton                matlab.ui.control.Button
        BinningSizeButtonGroup       matlab.ui.container.ButtonGroup
        Binning1                     matlab.ui.control.RadioButton
        Binning4                     matlab.ui.control.RadioButton
        Binning16                    matlab.ui.control.RadioButton
        Binning64                    matlab.ui.control.RadioButton
        Binning128                   matlab.ui.control.RadioButton
        n1024Button                  matlab.ui.control.RadioButton
        n4096Button                  matlab.ui.control.RadioButton
        ClearButton                  matlab.ui.control.Button
        Panel                        matlab.ui.container.Panel
        PsyCamEvanLabLabel           matlab.ui.control.Label
        Image                        matlab.ui.control.Image
        singlesubjectmodeLabel       matlab.ui.control.Label
        SaveLog                      matlab.ui.control.Button
        RightPanel                   matlab.ui.container.Panel
        FileLabel                    matlab.ui.control.Label
        FileDropDown                 matlab.ui.control.DropDown
        SaveImg                      matlab.ui.control.Button
        PlotPanel                    matlab.ui.container.Panel
        PlotAxes                     matlab.ui.control.UIAxes
        SaveallButton                matlab.ui.control.Button
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    
    properties (Access = private)
        data % create a variable "data" were store variables within the app
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            movegui(app.PsyCamsinglesubjectUIFigure,'center');
            pause(0.25);
            axtoolbar(app.PlotAxes,'visible','off');
            app.data.path_temp='';
            %uistack(app.PlotAxes,'top');
            %             title(app.PlotAxes, []);
            %             xlabel(app.PlotAxes, []);
            %             ylabel(app.PlotAxes, []);
            %             app.PlotAxes.XAxis.TickLabels = {};
            %             app.PlotAxes.YAxis.TickLabels = {};
            %             app.PlotAxes.XTick={};
            %             app.PlotAxes.YTick={};
            %             app.PlotAxes.XColor='none';
            %             app.PlotAxes.YColor='none';
            %             app.PlotAxes.Visible='on';
            %             app.PlotAxes.BackgroundColor=[0 0 0];
        end

        % Button pushed function: LoadButton
        function LoadButton_callback(app, event)
            
            [file, path]=uigetfile({'*.DNG','*.dng'},'Load DNG files','MultiSelect','on');
            
            %if press cancel or close the window
            if isequal(file,0)
                return;
            end
            
            if ~iscell(file)
                f=uiconfirm(app.PsyCamsinglesubjectUIFigure,'Warning! You selected only one file!','Warning!','options',{'Ok'},'Icon','warning');
                waitfor(f);
            end
            
            file=cellstr(file);
            n=numel(file);
            
            cfa_cell=cell(1,n);
            filename_cell=cell(1,n);
            
            %create a waitbar
            wb=uiprogressdlg(app.PsyCamsinglesubjectUIFigure,"Title",'Loading DNG files...');
            
            %clear image & colorbar
            cla(app.PlotAxes);
            colorbar(app.PlotAxes,'off');
            
            %clear img list e disable it
            app.FileDropDown.Items={};
            app.FileDropDown.Enable='off';
            
            %disable save buttons
            app.SaveImg.Enable='off';
            app.SaveLog.Enable='off';
            
            warning off;
            
            for i=1:n
                path_temp=strcat(path,file{i});
                cfa_temp=load_dng(path_temp);
                cfa_cell{i}=cfa_temp;
                filename_cell{i}=strcat(cfa_temp.path,'\', cfa_temp.name,'.DNG');
                wb.Value=i/n;
                wb.Message=sprintf('File %.0f over %.0f (%s)',i,n,cfa_temp.name);
            end
            
            delete(wb);
            
            %store the cfa
            app.data.img=cfa_cell;
            app.data.filelist=filename_cell;
            
            %enable binning btns
            set(findall(app.BinningSizeButtonGroup, '-property', 'enable'), 'enable', 'on')
            
            %enable computing btn
            app.ProcessButton.Enable='on';
            
        end

        % Selection changed function: BinningSizeButtonGroup
        function BinningSize_callback(app, event)
            
            %clear image and colorbar
            cla(app.PlotAxes);
            colorbar(app.PlotAxes,'off');
            
            %disable saving buttons & list
            app.SaveLog.Enable='off';
            app.SaveImg.Enable='off';
            app.FileDropDown.Enable='off';
            app.SaveallButton.Enable='off';
            
            selectedButton = app.BinningSizeButtonGroup.SelectedObject;
            
            %extract the bin size from the selected group
            txt=selectedButton.Text;
            txt_cell=strsplit(txt,':');
            bin_size=str2double(txt_cell{2});
            
            %store bin size
            app.data.bin_size=bin_size;
        end

        % Button pushed function: ProcessButton
        function Compute_callback(app, event)
            
            cfa_cell=app.data.img;
            
            if ~isfield(app.data,'bin_size')
                app.data.bin_size=1;
            end
            
            bin_size=app.data.bin_size;
            
            n=numel(cfa_cell);
            
            wb=uiprogressdlg(app.PsyCamsinglesubjectUIFigure,"Title",'Processing DNG files...');
            
            %initialize table
            typevar={'string','double','double','double','double','double','double'};
            namevar={'Filename','BinSize','Sum','Max','Min','Mean','Std'};
            metrics_table=table('Size',[n,7],'VariableTypes',typevar,'VariableNames',namevar);
            
            min_all=zeros(1,n);
            max_all=zeros(1,n);
            
            for i=1:n
                
                struct_temp=cfa_cell{i};
                cfa_temp=struct_temp.cfa;
                name_temp=struct_temp.name;
                
                img_temp=binning_matrix(cfa_temp,bin_size);
                
                %metrics: Sum, Max, Min, Mean, Std
                metrics_temp=metrics_matrix(img_temp);
                
                %store max and min
                min_all(i)=metrics_temp(3); %MIN is 3rd
                max_all(i)=metrics_temp(2); %MAX is 2nd
                
                %store in the table
                metrics_table(i,:)=[cellstr(name_temp),num2cell([bin_size,metrics_temp])];
                app.data.metrics_table=metrics_table;
                
                %store in the struct
                app.data.img{i}.bin_size=bin_size;
                app.data.img{i}.bin_matrix=img_temp;
                app.data.img{i}.metrics=metrics_temp;
                
                %update waitbar
                wb.Value=i/n;
                wb.Message=sprintf('File %.0f over %.0f (%s)',i,n,name_temp);
            end
            
            %default min-max value (for scaling)
            min_all=min(min_all);
            max_all=max(max_all);
            
            %auto or manual scaling?
            f=uiconfirm(app.PsyCamsinglesubjectUIFigure,'Select the scaling options','Scaling options','options',{'Auto','Manual'},'Icon','question');
            waitfor(f);
            
            if isequal(f,'Manual')
                ff=inputdlg({'Min','Max'},'Set manual scaling values',[1 50],string([min_all,max_all]));
                waitfor(ff);
                min_all=str2double(ff{1});
                max_all=str2double(ff{2});
            end
            
            %if the limits are flat, let's create a "fake" superior limit
            if isequal(min_all,max_all)
                max_all=min_all+eps;
            end
            
            %store range
            app.data.range=[min_all, max_all];
            
            delete(wb);
            
            %ask for save
            f=uiconfirm(app.PsyCamsinglesubjectUIFigure,'Do you want to save the results?','Save?','options',{'Yes','No'},'Icon','warning');
            waitfor(f);
            
            if isequal(f,'Yes')
                
                %save table to csv
                [file,path] = uiputfile('*.csv','Save results');
                
                if ~isequal(file,0)
                    wb=uiprogressdlg(app.PsyCamsinglesubjectUIFigure,"Title",'Saving results...','Indeterminate','on');
                    writetable(metrics_table,strcat(path,file));
                    delete(wb);
                end
                
            end
            
            app.SaveLog.Enable='on';
            app.SaveallButton.Enable='on';
            app.FileDropDown.Items=cellstr(app.data.filelist);
            app.FileDropDown.Enable='on';
            
            %plot image
            
            %clear axes
            cla(app.PlotAxes);
            value = app.FileDropDown.Value;
            index = find([app.data.filelist{:}] == value);
            img_temp=app.data.img{index}.bin_matrix;
            range_temp=app.data.img{index}.range;
            
            app.data.img_temp=img_temp;
            app.data.range_temp=range_temp;
            app.data.filename_temp=value;
            
            
            %scale axes
            r=size(img_temp,2);
            
            if r>1
                x=[1, size(img_temp,2)];
                y=[size(img_temp,1), 1];
                imagesc(app.PlotAxes,x,y,img_temp);
                app.PlotAxes.YDir='normal';
                colormap(app.PlotAxes,"hsv");
                app.PlotAxes.XLim=[1 r];
                app.PlotAxes.YLim=[1 r];
                app.PlotAxes.CLim=app.data.range;
                cb=colorbar(app.PlotAxes,'eastoutside','Fontsize',10);
                cb.Label.String=sprintf('Range: %.0f - %.0f',range_temp(1),range_temp(2));
                cb.Label.FontSize=12;
                tix=get(cb,'xtick')';
                set(cb,'xticklabel',num2str(tix,'%.2f'));
                %cb.Lable
                %title(app.PlotAxes,sprintf('Min = %.3f, Max=%.3f',range_temp(1),range_temp(2)),'FontSize',10,'FontWeight',"normal")
                
                
                app.data.colorbar=cb;
            else
                app.PlotAxes.XLim=[-1 1];
                app.PlotAxes.YLim=[-1 1];
                text(app.PlotAxes,0,0,sprintf('Val = %f\n[Range: %.0f - %.0f]',img_temp,range_temp(1),range_temp(2)),'HorizontalAlignment','center','FontSize',18);
                colorbar(app.PlotAxes,'off');
            end
            
            drawnow;
            
            app.SaveImg.Enable='on';
            app.FileDropDown.Enable='on';
            app.SaveallButton.Enable='on';
            
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.PsyCamsinglesubjectUIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {450, 450};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {322, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end

        % Value changed function: FileDropDown
        function FileDropDownValueChanged(app, event)
            %clear axes
            cla(app.PlotAxes);
            app.SaveImg.Enable='off';
            app.SaveallButton.Enable='off';
            
            value = app.FileDropDown.Value;
            index = find([app.data.filelist{:}] == value);
            img_temp=app.data.img{index}.bin_matrix;
            range_temp=app.data.img{index}.range;
            
            app.data.img_temp=img_temp;
            app.data.range_temp=range_temp;
            app.data.filename_temp=value;
            
            %scale axes
            r=size(img_temp,2);
            
            if r>1
                x=[1, size(img_temp,2)];
                y=[size(img_temp,1), 1];
                imagesc(app.PlotAxes,x,y,img_temp);
                app.PlotAxes.YDir='normal';
                colormap(app.PlotAxes,"hsv");
                app.PlotAxes.XLim=[1 r];
                app.PlotAxes.YLim=[1 r];
                app.PlotAxes.CLim=app.data.range;
                cb=colorbar(app.PlotAxes,'eastoutside','Fontsize',10);
                cb.Label.String=sprintf('Range: %.0f - %.0f',range_temp(1),range_temp(2));
                cb.Label.FontSize=12;
                tix=get(cb,'xtick')';
                set(cb,'xticklabel',num2str(tix,'%.2f'));
                
                %save the colorbar for subsequent use
                app.data.colorbar=cb;
                %title(app.PlotAxes,sprintf('Min = %.3f, Max=%.3f',range_temp(1),range_temp(2)),'FontSize',18)
            else
                app.PlotAxes.XLim=[-1 1];
                app.PlotAxes.YLim=[-1 1];
                text(app.PlotAxes,0,0,sprintf('Val = %f\n[Range: %.0f - %.0f]',img_temp,range_temp(1),range_temp(2)),'HorizontalAlignment','center','FontSize',18);
                colorbar(app.PlotAxes,'off');
                
            end
            
            
            drawnow;
            
            app.SaveImg.Enable='on';
            app.SaveallButton.Enable='on';


        end

        % Button pushed function: ClearButton
        function ClearButtonPushed(app, event)
            delete(app.PsyCamsinglesubjectUIFigure);
            pause(0.5);
            PsyCam_singlesubject;
        end

        % Button pushed function: SaveLog
        function SaveLogButtonPushed(app, event)
            
            [file,path] = uiputfile('*.csv','Save results');
            
            metrics_table=app.data.metrics_table;
            
            if ~isequal(file,0)
                wb=uiprogressdlg(app.PsyCamsinglesubjectUIFigure,"Title",'Saving results...','Indeterminate','on');
                writetable(metrics_table,strcat(path,file));
                delete(wb);
            end
        end

        % Button pushed function: SaveImg
        function SaveImgButtonPushed(app, event)
            
            %load temporary path
            path_temp=app.data.path_temp;
            
            img_temp=app.data.img_temp;
            
            def_file=app.data.filename_temp;
            [~, def_name]=fileparts(def_file);
            
            folder=uigetdir(path_temp,'Select saving folder');
            
            if ~isequal(folder,0)
                %update temporary folder
                app.data.path_temp=folder;
                
                wb=uiprogressdlg(app.PsyCamsinglesubjectUIFigure,"Title",'Saving image...','Indeterminate','on');
                
                img_struct.(def_name)=img_temp;
                save(strcat(folder,'\',def_name),'-struct','img_struct');
                
                save_UIaxes(app.PlotAxes,app.data.colorbar,strcat(folder,'\',def_name));
                delete(wb);
                
            end
            
        end

        % Close request function: PsyCamsinglesubjectUIFigure
        function PsyCamsinglesubjectUIFigureCloseRequest(app, event)
            delete(app)
            pause(0.5);
            PsyCam;
        end

        % Button pushed function: SaveallButton
        function SaveallButtonPushed(app, event)
            range=app.data.range;
            range_temp=app.data.range_temp;
            n=numel(app.data.img);
            names=app.data.filelist;
            
            folder=uigetdir('','Select saving folder');
            
            if ~isequal(folder,0)
                
                wb=uiprogressdlg(app.PsyCamsinglesubjectUIFigure,"Title",'Saving files...');
                
                for i=1:n
                    img_temp_temp=app.data.img{i};
                    img_temp=img_temp_temp.bin_matrix;
                    [~, name_temp]=fileparts(names{i});
                    r=size(img_temp,2);
                    
                    if r>1
                        
                        %create a new figure and plot the image
                        fig = figure;
                        fig.Visible = 'off';
                        figAxes = axes(fig);
                        
                        x=[1, size(img_temp,2)];
                        y=[size(img_temp,1), 1];
                        imagesc(figAxes,x,y,img_temp);
                        figAxes.YDir='normal';
                        colormap(app.PlotAxes,"hsv");
                        figAxes.XLim=[1 r];
                        figAxes.YLim=[1 r];
                        figAxes.CLim=range;
                        figAxes.XTick=[];
                        figAxes.YTick=[];
                        cb=colorbar(figAxes,'eastoutside','Fontsize',10);
                        cb.Label.String=sprintf('Range: %.0f - %.0f',range_temp(1),range_temp(2));
                        cb.Label.FontSize=12;
                        tix=get(cb,'xtick')';
                        set(cb,'xticklabel',num2str(tix,'%.2f'));
                        
                        %save the image
                        saveas(fig,strcat(folder,'\',name_temp),'png');
                        
                        close(fig);
                        
                        %update waitbar
                        wb.Value=i/n;
                        
                        
                    end
                    
                    img_struct=[]; %initialize img_struct for every image! if not, the struct will be incrementated!
                    img_struct.(name_temp)=img_temp;
                    save(strcat(folder,'\',name_temp),'-struct','img_struct');
                    wb.Message=sprintf('Saving file %.0f over %.0f (%s)',i,n,name_temp);
                    
                end
                
                delete(wb);
                
            end
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.PsyCamsinglesubjectUIFigure = uifigure('Visible', 'off');
            app.PsyCamsinglesubjectUIFigure.Name = 'PsyCam - single subject';
            app.PsyCamsinglesubjectUIFigure.CloseRequestFcn = createCallbackFcn(app, @PsyCamsinglesubjectUIFigureCloseRequest, true);
            app.PsyCamsinglesubjectUIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.PsyCamsinglesubjectUIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.LoadButton = uibutton(app.LeftPanel, 'push');
            app.LoadButton.ButtonPushedFcn = createCallbackFcn(app, @LoadButton_callback, true);
            app.LoadButton.Text = 'Load';
            app.ProcessButton = uibutton(app.LeftPanel, 'push');
            app.ProcessButton.ButtonPushedFcn = createCallbackFcn(app, @Compute_callback, true);
            app.ProcessButton.Enable = 'off';
            app.ProcessButton.Text = 'Process';
            app.BinningSizeButtonGroup = uibuttongroup(app.LeftPanel);
            app.BinningSizeButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @BinningSize_callback, true);
            app.BinningSizeButtonGroup.Title = 'Binning Size';
            app.Binning1 = uiradiobutton(app.BinningSizeButtonGroup);
            app.Binning1.Enable = 'off';
            app.Binning1.Tooltip = {''};
            app.Binning1.Text = 'n:1';
            app.Binning1.Value = true;
            app.Binning4 = uiradiobutton(app.BinningSizeButtonGroup);
            app.Binning4.Enable = 'off';
            app.Binning4.Tooltip = {'4'};
            app.Binning4.Text = 'n:4';
            app.Binning16 = uiradiobutton(app.BinningSizeButtonGroup);
            app.Binning16.Enable = 'off';
            app.Binning16.Tooltip = {'16'};
            app.Binning16.Text = 'n:16';
            app.Binning64 = uiradiobutton(app.BinningSizeButtonGroup);
            app.Binning64.Enable = 'off';
            app.Binning64.Tooltip = {'64'};
            app.Binning64.Text = 'n:64';
            app.Binning128 = uiradiobutton(app.BinningSizeButtonGroup);
            app.Binning128.Enable = 'off';
            app.Binning128.Tooltip = {'128'; ''};
            app.Binning128.Text = 'n:256';
            app.n1024Button = uiradiobutton(app.BinningSizeButtonGroup);
            app.n1024Button.Enable = 'off';
            app.n1024Button.Text = 'n:1024';
            app.n4096Button = uiradiobutton(app.BinningSizeButtonGroup);
            app.n4096Button.Enable = 'off';
            app.n4096Button.Text = 'n:4096';
            app.ClearButton = uibutton(app.LeftPanel, 'push');
            app.ClearButton.ButtonPushedFcn = createCallbackFcn(app, @ClearButtonPushed, true);
            app.ClearButton.Text = 'Clear';
            app.Panel = uipanel(app.LeftPanel);
            app.PsyCamEvanLabLabel = uilabel(app.Panel);
            app.PsyCamEvanLabLabel.Text = 'PsyCam - EvanLab';
            app.Image = uiimage(app.Panel);
            app.Image.ImageSource = 'evanlab.png';
            app.singlesubjectmodeLabel = uilabel(app.Panel);
            app.singlesubjectmodeLabel.Text = '[single subject mode]';
            app.SaveLog = uibutton(app.LeftPanel, 'push');
            app.SaveLog.ButtonPushedFcn = createCallbackFcn(app, @SaveLogButtonPushed, true);
            app.SaveLog.Enable = 'off';
            app.SaveLog.Text = 'Save';
            app.RightPanel = uipanel(app.GridLayout);
            app.FileLabel = uilabel(app.RightPanel);
            app.FileLabel.Enable = 'off';
            app.FileLabel.Text = {'File'; ''};
            app.FileDropDown = uidropdown(app.RightPanel);
            app.FileDropDown.Items = {};
            app.FileDropDown.ValueChangedFcn = createCallbackFcn(app, @FileDropDownValueChanged, true);
            app.FileDropDown.Enable = 'off';
            app.FileDropDown.Value = {};
            app.SaveImg = uibutton(app.RightPanel, 'push');
            app.SaveImg.ButtonPushedFcn = createCallbackFcn(app, @SaveImgButtonPushed, true);
            app.SaveImg.Enable = 'off';
            app.SaveImg.Text = 'Save';
            app.PlotPanel = uipanel(app.RightPanel);
            app.PlotAxes = uiaxes(app.PlotPanel);
            title(app.PlotAxes, '')
            xlabel(app.PlotAxes, '')
            ylabel(app.PlotAxes, '')
            app.PlotAxes.XTick = [];
            app.PlotAxes.YTick = [];
            app.SaveallButton = uibutton(app.RightPanel, 'push');
            app.SaveallButton.ButtonPushedFcn = createCallbackFcn(app, @SaveallButtonPushed, true);
            app.SaveallButton.Enable = 'off';
            app.SaveallButton.Text = 'Save all';
            % Show the figure after all components are created
            app.PsyCamsinglesubjectUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = PsyCam_singlesubject

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.PsyCamsinglesubjectUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.PsyCamsinglesubjectUIFigure)
        end
    end
end