classdef PsyCam_ssi < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        PsyCamssiUIFigure         matlab.ui.Figure
        GridLayout                matlab.ui.container.GridLayout
        LeftPanel                 matlab.ui.container.Panel
        LoadButton                matlab.ui.control.Button
        ProcessButton             matlab.ui.control.Button
        ClearButton               matlab.ui.control.Button
        Panel                     matlab.ui.container.Panel
        PsyCamEvanLabLabel        matlab.ui.control.Label
        Image                     matlab.ui.control.Image
        similarityindexmodeLabel  matlab.ui.control.Label
        SaveLog                   matlab.ui.control.Button
        SSIMParametersPanel       matlab.ui.container.Panel
        LuminanceEditFieldLabel   matlab.ui.control.Label
        LuminanceEditField        matlab.ui.control.NumericEditField
        ContrastEditFieldLabel    matlab.ui.control.Label
        ContrastEditField         matlab.ui.control.NumericEditField
        StructureEditFieldLabel   matlab.ui.control.Label
        StructureEditField        matlab.ui.control.NumericEditField
        RightPanel                matlab.ui.container.Panel
        FileLabel                 matlab.ui.control.Label
        FileDropDown              matlab.ui.control.DropDown
        SaveImg                   matlab.ui.control.Button
        PlotPanel                 matlab.ui.container.Panel
        PlotAxes                  matlab.ui.control.UIAxes
        Label                     matlab.ui.control.Label
        SaveallButton             matlab.ui.control.Button
        TargetDropDownLabel       matlab.ui.control.Label
        TargetDropDown            matlab.ui.control.DropDown
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    
    properties (Access = private)
        data % create a variable "data" were store variables within the app
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            movegui(app.PsyCamssiUIFigure,'center');
            pause(0.25);
            axtoolbar(app.PlotAxes,'visible','off');
            app.PlotAxes.YDir='normal';
            app.PlotAxes.XDir='normal';
            app.data.path_temp='';
            %uistack(app.PlotAxes,'top');
            %             title(app.PlotAxes, []);
            %             xlabel(app.PlotAxes, []);
            %             ylabel(app.PlotAxes, []);
            %             app.PlotAxes.XAxis.TickLabels = {};
            %             app.PlotAxes.YAxis.TickLabels = {};
            %             app.PlotAxes.XTick={};
            %             app.PlotAxes.YTick={};
            %             app.PlotAxes.XColor='none';
            %             app.PlotAxes.YColor='none';
            %             app.PlotAxes.Visible='on';
            %             app.PlotAxes.BackgroundColor=[0 0 0];
        end

        % Button pushed function: LoadButton
        function LoadButton_callback(app, event)
            
            [file, path]=uigetfile(strcat(app.data.path_temp,'*.mat'),'Load MAT files','MultiSelect','on');
            
            %if press cancel or close the window
            if isequal(file,0)
                return;
            end
            
            %update path
            app.data.path_temp=path;
            
            if ~iscell(file)
                f=uiconfirm(app.PsyCamssiUIFigure,'Warning! You selected only one file!','Warning!','options',{'Ok'},'Icon','warning');
                waitfor(f);
            end
            
            file=cellstr(file);
            n=numel(file);
            
            filename_cell=cell(1,n);
            
            %create a waitbar
            wb=uiprogressdlg(app.PsyCamssiUIFigure,"Title",'Loading DNG files...');
            
            %clear image & colorbar
            cla(app.PlotAxes);
            colorbar(app.PlotAxes,'off');
            
            %clear img list e disable it
            app.FileDropDown.Items={};
            app.FileDropDown.Enable='off';
            
            %disable save buttons
            app.SaveImg.Enable='off';
            app.SaveLog.Enable='off';
            
            warning off;
            
            for i=1:n
                path_temp=strcat(path,file{i});
                
                %load m file
                mat_temp=load(path_temp);
                name=fieldnames(mat_temp);
                
                %store loaded file into the cell
                mat_cell{i}.data=mat_temp.(name{1});
                mat_cell{i}.name=name;
                
                filename_cell{i}=strcat(path_temp);
                wb.Value=i/n;
                wb.Message=sprintf('File %.0f over %.0f (%s)',i,n,name{1});
            end
            
            delete(wb);
            
            %store the cfa
            app.data.mat=mat_cell;
            app.data.filelist=filename_cell;
            
            %enable computing btn
            app.ProcessButton.Enable='on';
            
            %enable parameters panel
            set(findall(app.SSIMParametersPanel, '-property', 'enable'), 'enable', 'on');
            
        end

        % Button pushed function: ProcessButton
        function Compute_callback(app, event)
            
            app.SaveallButton.Enable='off';
            app.SaveImg.Enable='off';
            app.FileDropDown.Enable='off';
            app.SaveallButton.Enable='off';
            
            %read from Parameters
            lum=app.LuminanceEditField.Value;
            cont=app.ContrastEditField.Value;
            struc=app.StructureEditField.Value;
            param=[lum, cont, struc];
            
            %disable parameters panel
            set(findall(app.SSIMParametersPanel, '-property', 'enable'), 'enable', 'off');
            
            %clear axes
            cla(app.PlotAxes);
            colorbar(app.PlotAxes,'off');
            title(app.PlotAxes,'');
            
            %initialize text
            app.Label.Text='';
            
            %initialize target menu
            app.TargetDropDown.Items={''};
            app.TargetDropDown.Value={''};
            app.TargetDropDown.Enable='off';
            
            %initialize file menu
            app.FileDropDown.Items={''};
            app.FileDropDown.Value={''};
            app.FileDropDown.Enable='off';
            
            target=load('target.mat');
            n=numel(app.data.mat);
            
            name_target=fieldnames(target);
            app.data.targetlist=name_target;
            
            n_target=numel(name_target);
            pos_max=zeros(1,n); %vector storing the position of best match
            
            wb=uiprogressdlg(app.PsyCamssiUIFigure,"Title",'Processing MAT files...');
            
            %initialize table
            typevar=cat(2,{'string'},repmat({'double'},1,n_target));
            namevar=cat(2,{'Filename'},name_target');
            metrics_table=table('Size',[n,n_target+1],'VariableTypes',typevar,'VariableNames',namevar);
            target_table=table('Size',[n,3],'VariableTypes',{'string','string','double'},'VariableNames',{'Filename','Best_target','SSIM'});
            file_table=table('Size',[n_target,3],'VariableTypes',{'string','string','double'},'VariableNames',{'Target','Best_Filename','SSIM'});
            
            
            ssim_out=cell(1,n);
            
            min_val=zeros(n,n_target);
            max_val=zeros(n,n_target);
            
            for i=1:n %for every file
                
                mat_temp=app.data.mat{i}.data;
                name_temp=app.data.mat{i}.name{1};
                bin_size=size(mat_temp,2).^2;
                
                for j=1:n_target %for every target
                    
                    target_temp=binning_matrix(target.(name_target{j}),bin_size);
                    [val,map]=ssim(mat_temp,target_temp,'Exponents',param);
                    
                    %store range
                    range_temp=[min(min(map)), max(max(map))];
                    
                    %update min_val & max_val
                    min_val(i,j)=range_temp(1);
                    max_val(i,j)=range_temp(2);
                    
                    %store
                    metrics_table(i,:).Filename=name_temp;
                    metrics_table(i,:).(name_target{j})=val;
                    ssim_out{i}.idx.(name_target{j})=val;
                    ssim_out{i}.map.(name_target{j})=map;
                    ssim_out{i}.range.(name_target{j})=range_temp;
                end
                
                %find the best matching target and store in the last column
                [~,pos_max_temp]=max(metrics_table{i,2:end});
                name_target_temp=name_target(pos_max_temp);
                
                %store the max in the max vector
                pos_max(i)=pos_max_temp;
                
                %store in the target table
                target_table(i,:).Filename=name_temp;
                target_table(i,:).Best_target=name_target_temp;
                target_table(i,:).SSIM=ssim_out{i}.idx.(name_target_temp{1});
                
                
                %update waitbar
                wb.Value=i/n;
                wb.Message=sprintf('File %.0f over %.0f (%s)',i,n,name_temp);
            end
            
            %build file table
            [val_file,idx_file]=max(metrics_table{:,2:end},[],1);
            file_table.Target=name_target;
            file_table.Best_Filename=metrics_table.Filename(idx_file);
            file_table.SSIM=val_file';
            
            app.data.best_match=pos_max;
            app.data.ssim=ssim_out;
            
            [~,pos_max_temp]=max(metrics_table{:,2:end});
            filename_target=app.data.filelist(pos_max_temp);
            app.data.name_best_match=filename_target;
            
            
            %default min-max value (for scaling)
            min_all=min(min(min_val));
            max_all=max(max(max_val));
            
            %auto or manual scaling? if manual, rewrite max_all and min_all
            f=uiconfirm(app.PsyCamssiUIFigure,'Select the scaling options','Scaling options','options',{'Auto','Manual'},'Icon','question');
            waitfor(f);
            
            if isequal(f,'Manual')
                ff=inputdlg({'Min','Max'},'Set manual scaling values',[1 50],string([min_all,max_all]));
                waitfor(ff);
                min_all=str2double(ff{1});
                max_all=str2double(ff{2});
            end
            
            %store range
            app.data.range=[min_all, max_all];
            
            delete(wb);
            
            %ask for save
            f=uiconfirm(app.PsyCamssiUIFigure,'Do you want to save the results?','Save?','options',{'Yes','No'},'Icon','warning');
            waitfor(f);
            
            if isequal(f,'Yes')
                
                %save File mode
                [file,path] = uiputfile(strcat(app.data.path_temp, '*.csv'),'Save results ');
                
                if ~isequal(file,0)
                    wb=uiprogressdlg(app.PsyCamssiUIFigure,"Title",'Saving results...','Indeterminate','on');
                    writetable(metrics_table,strcat(path,file));
                    
                    [~,file,ext]=fileparts(file);
                    writetable(target_table,strcat(path,file,'_best_target',ext));
                    writetable(file_table,strcat(path,file,'_best_file',ext));
                    delete(wb);
                    
                    %update path
                    app.data.path_temp=path;
                    
                end
                
                
            end
            
            app.data.metrics_table=metrics_table;
            app.data.target_table=target_table;
            app.data.file_table=file_table;
            app.SaveLog.Enable='on';
            app.SaveallButton.Enable='on';
            
            %enable file dropdown menu
            app.FileDropDown.Items=cat(2,{'...select'},cellstr(app.data.filelist));
            app.FileDropDown.Enable='on';
            
            %enable targer file dropdown menu
            app.TargetDropDown.Items=cat(1,{'...select'},cellstr(app.data.targetlist));
            app.TargetDropDown.Enable='on';
            
            %clear axes
            cla(app.PlotAxes);
            
            app.FileDropDown.Enable='on';
            app.SaveallButton.Enable='on';
            
            %enable parameters panel
            set(findall(app.SSIMParametersPanel, '-property', 'enable'), 'enable', 'on');
            
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.PsyCamssiUIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {519, 519};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {322, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end

        % Value changed function: FileDropDown
        function FileDropDownValueChanged(app, event)
            %clear axes
            
            
            %clear axes
            cla(app.PlotAxes);
            colorbar(app.PlotAxes,'off');
            title(app.PlotAxes,'');
            
            %initialize text
            app.Label.Text='';
            
            %initialize target menu
            app.TargetDropDown.Value='...select';
            app.TargetDropDown.Enable='off';
            
            %disable buttons
            app.SaveImg.Enable='off';
            app.SaveallButton.Enable='off';
            
            %file
            value = app.FileDropDown.Value;
            
            if isequal(value,'...select')
                cla(app.PlotAxes);
                app.TargetDropDown.Enable='on';
                return
            end
            index = find(string([app.data.filelist]) == value);
            [~,name_temp]=fileparts(app.data.filelist{index});
            
            %target dropdown
            %index_target=find(string([app.data.targetlist]) == value_target);
            index_target=app.data.best_match(index);
            
            %update name
            %app.TargetDropDown.Value=app.data.targetlist{index_target};
            
            img_temp=app.data.ssim{index}.map.(app.data.targetlist{index_target});
            
            app.data.img_temp=img_temp;
            range_temp=app.data.range;
            app.data.filename_temp=value;
            app.data.targetname_temp=(app.data.targetlist{index_target});
            
            %scale axes
            r=size(img_temp,2);
            
            if r>1
                
                x=[1, size(img_temp,2)];
                y=[size(img_temp,1) 1];
                imagesc(app.PlotAxes,x,y,img_temp);
                app.PlotAxes.YDir='normal';
                colormap(app.PlotAxes,"hsv");
                
                app.PlotAxes.XLim=[1 r];
                app.PlotAxes.YLim=[1 r];
                app.PlotAxes.CLim=[range_temp(1),range_temp(2)];
                cb=colorbar(app.PlotAxes,'eastoutside','Fontsize',10);
                cb.Label.String=sprintf('Range: %.3f - %.3f',range_temp(1),range_temp(2));
                cb.Label.FontSize=12;
                
                tix=get(cb,'xtick')';
                set(cb,'xticklabel',num2str(tix,'%.3f'));
                
                %save the colorbar for subsequent use
                app.data.colorbar=cb;
                
                app.Label.Text=sprintf('Choosen file: %s | Best target: %s',name_temp,app.data.targetlist{index_target});
                %app.PlotAxes.Title.String=sprintf('Best matching target: ''%s''',app.data.targetlist{index_target});
                %app.PlotAxes.Title.FontSize=12;
            else
                app.PlotAxes.XLim=[-1 1];
                app.PlotAxes.YLim=[-1 1];
                text(app.PlotAxes,0,0,sprintf('Val = %f\n[Range: %.3f - %.3f]',img_temp,range_temp(1),range_temp(2)),'HorizontalAlignment','center','FontSize',18);
                colorbar(app.PlotAxes,'off');
                app.Label.Text=sprintf('Choosen file: %s | Best target: %s',name_temp,app.data.targetlist{index_target});
                %app.PlotAxes.Title.FontSize=12;
            end
            
            
            drawnow;
            
            app.SaveImg.Enable='on';
            app.SaveallButton.Enable='on';
            app.TargetDropDown.Enable='on';
            
        end

        % Button pushed function: ClearButton
        function ClearButtonPushed(app, event)
            delete(app.PsyCamssiUIFigure);
            pause(0.5);
            PsyCam_ssi;
        end

        % Button pushed function: SaveLog
        function SaveLogButtonPushed(app, event)
            
            metrics_table=app.data.metrics_table;
            target_table=app.data.target_table;
            file_table=app.data.file_table;
            
            f=uiconfirm(app.PsyCamssiUIFigure,'Do you want to save the results?','Save?','options',{'Yes','No'},'Icon','warning');
            waitfor(f);
            
            if isequal(f,'Yes')
                
                %save File mode
                [file,path] = uiputfile(strcat(app.data.path_temp, '*.csv'),'Save results ');
                
                if ~isequal(file,0)
                    wb=uiprogressdlg(app.PsyCamssiUIFigure,"Title",'Saving results...','Indeterminate','on');
                    writetable(metrics_table,strcat(path,file));
                    
                    [~,file,ext]=fileparts(file);
                    writetable(target_table,strcat(path,file,'_best_target',ext));
                    writetable(file_table,strcat(path,file,'_best_file',ext));
                    delete(wb);
                    
                    %update path
                    app.data.path_temp=path;
                    
                end
                
                
            end
        end

        % Button pushed function: SaveImg
        function SaveImgButtonPushed(app, event)
            
            %load temporary path
            path_temp=app.data.path_temp;
            
            img_temp=app.data.img_temp;
            
            def_file=app.data.filename_temp;
            def_target=app.data.targetname_temp;
            
            [~, def_name]=fileparts(def_file);
            
            folder=uigetdir(path_temp,'Select saving folder');
            
            
            if ~isequal(folder,0)
                
                wb=uiprogressdlg(app.PsyCamssiUIFigure,"Title",'Saving image...','Indeterminate','on');
                
                img_struct.(def_name)=img_temp;
                save(strcat(folder,'\',def_name,'_',def_target),'-struct','img_struct');
                
                save_UIaxes(app.PlotAxes,app.data.colorbar,strcat(folder,'\',def_name,'_',def_target));
                delete(wb);
                
            end
            
            %update temporary folder
            app.data.path_temp=folder;
            
        end

        % Close request function: PsyCamssiUIFigure
        function PsyCamssiUIFigureCloseRequest(app, event)
            delete(app)
            pause(0.5);
            PsyCam;
        end

        % Button pushed function: SaveallButton
        function SaveallButtonPushed(app, event)
            range=app.data.range;
            range_temp=app.data.range;
            
            n=numel(app.data.ssim);
            n_target=numel(app.data.targetlist);
            
            names=app.data.filelist;
            
            save_type=uiconfirm(app.PsyCamssiUIFigure,'Select save options','Save options',...
                'Options',{'All','Best for Targets','Cancel'},'CancelOption',3);
            
            if isequal(save_type,'Cancel')
                return
            end
            
            folder=uigetdir(app.data.path_temp,'Select saving folder');
            
            if ~isequal(folder,0)
                
                wb=uiprogressdlg(app.PsyCamssiUIFigure,"Title",'Saving files...');
                
                if isequal(save_type,'All') %save all images
                    %save all the images
                    for i=1:n
                        for j=1:n_target
                            
                            img_temp=app.data.ssim{i}.map.(app.data.targetlist{j});
                            
                            [~, name_temp]=fileparts(names{i});
                            name_target=(app.data.targetlist{j});
                            
                            r=size(img_temp,2);
                            
                            if r>1
                                
                                %create a new figure and plot the image
                                fig = figure;
                                fig.Visible = 'off';
                                figAxes = axes(fig);
                                x=[1, size(img_temp,2)];
                                y=[size(img_temp,1),1];
                                imagesc(figAxes,x,y,img_temp);
                                figAxes.YDir='normal';
                                colormap(figAxes,"hsv");
                                figAxes.XLim=[1 r];
                                figAxes.YLim=[1 r];
                                figAxes.CLim=range;
                                figAxes.XTick=[];
                                figAxes.YTick=[];
                                cb=colorbar(figAxes,'eastoutside','Fontsize',10);
                                cb.Label.String=sprintf('Range: %.3f - %.3f',range_temp(1),range_temp(2));
                                cb.Label.FontSize=12;
                                tix=get(cb,'xtick')';
                                set(cb,'xticklabel',num2str(tix,'%.3f'));
                                
                                %save the image
                                saveas(fig,strcat(folder,'\',name_temp, '_', name_target),'png');
                                
                                close(fig);
                                
                                %update waitbar
                                wb.Value=i/n;
                                
                            end
                        end
                        
                        
                        img_struct.(name_temp)=img_temp;
                        save(strcat(folder,'\',name_temp,'_',name_target),'-struct','img_struct');
                        wb.Message=sprintf('Saving file %.0f over %.0f (%s)',i,n,name_temp);
                        
                    end
                else %save only 4 target
                    
                    n=numel(app.data.targetlist);
                    
                    for i=1:n
                        value_target=app.data.targetlist{i};
                        name_target=value_target;
                        
                        index_target=find(string([app.data.targetlist]) == value_target);
                        value=app.data.name_best_match{index_target};
                        
                        %index file
                        index=find(string(app.data.filelist)==app.data.name_best_match{index_target});
                        [~,name_temp]=fileparts(app.data.filelist{index});
                        
                        img_temp=app.data.ssim{index}.map.(app.data.targetlist{index_target});
                        
                        app.data.img_temp=img_temp;
                        range_temp=app.data.range;
                        app.data.filename_temp=value;
                        app.data.targetname_temp=(app.data.targetlist{index_target});
                        
                        %scale axes
                        r=size(img_temp,2);
                        
                        if r>1
                            fig = figure;
                            fig.Visible = 'off';
                            figAxes = axes(fig);
                            x=[1, size(img_temp,2)];
                            y=[size(img_temp,1),1];
                            imagesc(figAxes,x,y,img_temp);
                            figAxes.YDir='normal';
                            colormap(figAxes,"hsv");
                            figAxes.XLim=[1 r];
                            figAxes.YLim=[1 r];
                            figAxes.CLim=range;
                            figAxes.XTick=[];
                            figAxes.YTick=[];
                            cb=colorbar(figAxes,'eastoutside','Fontsize',10);
                            cb.Label.String=sprintf('Range: %.3f - %.3f',range_temp(1),range_temp(2));
                            cb.Label.FontSize=12;
                            tix=get(cb,'xtick')';
                            set(cb,'xticklabel',num2str(tix,'%.3f'));
                            
                            %save the image
                            saveas(fig,strcat(folder,'\','best','_',name_target, '_', name_temp),'png');
                            
                            close(fig);
                            
                            %update waitbar
                            wb.Value=i/n;
                            
                        end
                        
                        img_struct.(name_temp)=img_temp;
                        save(strcat(folder,'\','best','_',name_target, '_', name_temp),'-struct','img_struct');
                        wb.Message=sprintf('Saving file %.0f over %.0f (%s)',i,n,name_temp);
                        
                    end
                    
                end
            end
            
            %update temporary folder
            app.data.path_temp=folder;
            
            delete(wb);
            
        end

        % Value changed function: TargetDropDown
        function TargetDropDownValueChanged(app, event)
            %clear axes
            cla(app.PlotAxes);
            colorbar(app.PlotAxes,'off');
            title(app.PlotAxes,'');
            
            %initialize target menu
            app.FileDropDown.Value='...select';
            app.FileDropDown.Enable='off';
            
            %initialize text
            app.Label.Text='';
            
            %disable buttons
            app.SaveImg.Enable='off';
            app.SaveallButton.Enable='off';
            
            %get the value
            value_target=app.TargetDropDown.Value;
            
            if isequal(value_target,'...select')
                cla(app.PlotAxes);
                app.FileDropDown.Enable='on';
                return;
            end
            
            
            %target dropdown
            index_target=find(string([app.data.targetlist]) == value_target);
            value=app.data.name_best_match{index_target};
            
            %index file
            index=find(string(app.data.filelist)==app.data.name_best_match{index_target});
            [~,name_temp]=fileparts(app.data.filelist{index});
            
            img_temp=app.data.ssim{index}.map.(app.data.targetlist{index_target});
            
            app.data.img_temp=img_temp;
            range_temp=app.data.range;
            app.data.filename_temp=value;
            app.data.targetname_temp=(app.data.targetlist{index_target});
            
            %scale axes
            r=size(img_temp,2);
            
            if r>1
                x=[1, size(img_temp,2)];
                y=[size(img_temp,1),1];
                imagesc(app.PlotAxes,x,y,img_temp);
                app.PlotAxes.YDir='normal';
                colormap(app.PlotAxes,"hsv");
                app.PlotAxes.XLim=[1 r];
                app.PlotAxes.YLim=[1 r];
                app.PlotAxes.CLim=app.data.range;
                cb=colorbar(app.PlotAxes,'eastoutside','Fontsize',10);
                cb.Label.String=sprintf('Range: %.3f - %.3f',range_temp(1),range_temp(2));
                cb.Label.FontSize=12;
                tix=get(cb,'xtick')';
                set(cb,'xticklabel',num2str(tix,'%.3f'));
                
                %save the colorbar for subsequent use
                app.data.colorbar=cb;
                app.Label.Text=sprintf('Choosen target: %s | Best file: %s',app.data.targetlist{index_target},name_temp);
            else
                app.PlotAxes.XLim=[-1 1];
                app.PlotAxes.YLim=[-1 1];
                text(app.PlotAxes,0,0,sprintf('Val = %f\n[Range: %.0f - %.0f]',img_temp,range_temp(1),range_temp(2)),'HorizontalAlignment','center','FontSize',18);
                colorbar(app.PlotAxes,'off');
                app.Label.Text=sprintf('Choosen target: %s | Best file: %s',app.data.targetlist{index_target},name_temp);
                
            end
            
            
            drawnow;
            
            app.SaveImg.Enable='on';
            app.SaveallButton.Enable='on';
            
            %enable file menu
            app.FileDropDown.Enable='on';
            
        end

        % Value changed function: LuminanceEditField
        function LuminanceEditFieldValueChanged(app, event)
            value = app.LuminanceEditField.Value;
            
        end

        % Value changed function: ContrastEditField
        function ContrastEditFieldValueChanged(app, event)
            value = app.ContrastEditField.Value;
            
        end

        % Value changed function: StructureEditField
        function StructureEditFieldValueChanged(app, event)
            value = app.StructureEditField.Value;
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.PsyCamssiUIFigure = uifigure('Visible', 'off');
            app.PsyCamssiUIFigure.Name = 'PsyCam - similarity index';
            app.PsyCamssiUIFigure.CloseRequestFcn = createCallbackFcn(app, @PsyCamssiUIFigureCloseRequest, true);
            app.PsyCamssiUIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.PsyCamssiUIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.LoadButton = uibutton(app.LeftPanel, 'push');
            app.LoadButton.ButtonPushedFcn = createCallbackFcn(app, @LoadButton_callback, true);
            app.LoadButton.Text = 'Load';
            app.ProcessButton = uibutton(app.LeftPanel, 'push');
            app.ProcessButton.ButtonPushedFcn = createCallbackFcn(app, @Compute_callback, true);
            app.ProcessButton.Enable = 'off';
            app.ProcessButton.Text = 'Process';
            app.ClearButton = uibutton(app.LeftPanel, 'push');
            app.ClearButton.ButtonPushedFcn = createCallbackFcn(app, @ClearButtonPushed, true);
            app.ClearButton.Text = 'Clear';
            app.Panel = uipanel(app.LeftPanel);
            app.PsyCamEvanLabLabel = uilabel(app.Panel);
            app.PsyCamEvanLabLabel.Text = 'PsyCam - EvanLab';
            app.Image = uiimage(app.Panel);
            app.Image.ImageSource = 'evanlab.png';
            app.similarityindexmodeLabel = uilabel(app.Panel);
            app.similarityindexmodeLabel.Text = '[similarity index mode]';
            app.SaveLog = uibutton(app.LeftPanel, 'push');
            app.SaveLog.ButtonPushedFcn = createCallbackFcn(app, @SaveLogButtonPushed, true);
            app.SaveLog.Enable = 'off';
            app.SaveLog.Text = 'Save';
            app.SSIMParametersPanel = uipanel(app.LeftPanel);
            app.SSIMParametersPanel.Title = 'SSIM Parameters';
            app.LuminanceEditFieldLabel = uilabel(app.SSIMParametersPanel);
            app.LuminanceEditFieldLabel.Enable = 'off';
            app.LuminanceEditFieldLabel.Text = 'Luminance';
            app.LuminanceEditField = uieditfield(app.SSIMParametersPanel, 'numeric');
            app.LuminanceEditField.Limits = [0 Inf];
            app.LuminanceEditField.ValueDisplayFormat = '%.2f';
            app.LuminanceEditField.ValueChangedFcn = createCallbackFcn(app, @LuminanceEditFieldValueChanged, true);
            app.LuminanceEditField.Enable = 'off';
            app.LuminanceEditField.Value = 1;
            app.ContrastEditFieldLabel = uilabel(app.SSIMParametersPanel);
            app.ContrastEditFieldLabel.Enable = 'off';
            app.ContrastEditFieldLabel.Text = 'Contrast';
            app.ContrastEditField = uieditfield(app.SSIMParametersPanel, 'numeric');
            app.ContrastEditField.Limits = [0 Inf];
            app.ContrastEditField.ValueDisplayFormat = '%.2f';
            app.ContrastEditField.ValueChangedFcn = createCallbackFcn(app, @ContrastEditFieldValueChanged, true);
            app.ContrastEditField.Enable = 'off';
            app.ContrastEditField.Value = 1;
            app.StructureEditFieldLabel = uilabel(app.SSIMParametersPanel);
            app.StructureEditFieldLabel.Enable = 'off';
            app.StructureEditFieldLabel.Text = 'Structure';
            app.StructureEditField = uieditfield(app.SSIMParametersPanel, 'numeric');
            app.StructureEditField.Limits = [0 Inf];
            app.StructureEditField.ValueDisplayFormat = '%.2f';
            app.StructureEditField.ValueChangedFcn = createCallbackFcn(app, @StructureEditFieldValueChanged, true);
            app.StructureEditField.Enable = 'off';
            app.StructureEditField.Value = 1;
            app.RightPanel = uipanel(app.GridLayout);
            app.FileLabel = uilabel(app.RightPanel);
            app.FileLabel.Enable = 'off';
            app.FileLabel.Text = {'File'; ''};
            app.FileDropDown = uidropdown(app.RightPanel);
            app.FileDropDown.Items = {};
            app.FileDropDown.ValueChangedFcn = createCallbackFcn(app, @FileDropDownValueChanged, true);
            app.FileDropDown.Enable = 'off';
            app.FileDropDown.Value = {};
            app.SaveImg = uibutton(app.RightPanel, 'push');
            app.SaveImg.ButtonPushedFcn = createCallbackFcn(app, @SaveImgButtonPushed, true);
            app.SaveImg.Enable = 'off';
            app.SaveImg.Text = 'Save';
            app.PlotPanel = uipanel(app.RightPanel);
            app.PlotAxes = uiaxes(app.PlotPanel);
            title(app.PlotAxes, '')
            xlabel(app.PlotAxes, '')
            ylabel(app.PlotAxes, '')
            app.PlotAxes.XTick = [];
            app.PlotAxes.YTick = [];
            app.Label = uilabel(app.PlotPanel);
            app.Label.Text = '';
            app.SaveallButton = uibutton(app.RightPanel, 'push');
            app.SaveallButton.ButtonPushedFcn = createCallbackFcn(app, @SaveallButtonPushed, true);
            app.SaveallButton.Enable = 'off';
            app.SaveallButton.Text = 'Save all';
            app.TargetDropDownLabel = uilabel(app.RightPanel);
            app.TargetDropDownLabel.Enable = 'off';
            app.TargetDropDownLabel.Text = 'Target';
            app.TargetDropDown = uidropdown(app.RightPanel);
            app.TargetDropDown.Items = {};
            app.TargetDropDown.ValueChangedFcn = createCallbackFcn(app, @TargetDropDownValueChanged, true);
            app.TargetDropDown.Enable = 'off';
            app.TargetDropDown.Value = {};
            % Show the figure after all components are created
            app.PsyCamssiUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = PsyCam_ssi

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.PsyCamssiUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.PsyCamssiUIFigure)
        end
    end
end